classdef HomePage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image8                     matlab.ui.control.Image
        YellowButton               matlab.ui.control.Button
        PurpleButton               matlab.ui.control.Button
        RedButton                  matlab.ui.control.Button
        PinkButton                 matlab.ui.control.Button
        GreenButton                matlab.ui.control.Button
        BlueButton                 matlab.ui.control.Button
        Image7                     matlab.ui.control.Image
        Image6                     matlab.ui.control.Image
        Image5                     matlab.ui.control.Image
        Image4                     matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        TakethequizButton          matlab.ui.control.Button
        ClickLetsPlaytobeginLabel  matlab.ui.control.Label
        LetsPlayButton             matlab.ui.control.Button
        CreatedbyKareenaSandhuSalinaMariMentorandMachaelaCruzLabel  matlab.ui.control.Label
        BrainGameLabel             matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LetsPlayButton
        function LetsPlayButtonPushed(app, event)
            % % app.ClickLetsPlaytobeginLabel.Visible = 'off'; % Hide the label
            % app.LetsPlayButton.Visible = 'off'; % Hide the button
            % app.CreatedbyKareenaSandhuSalinaMariMentorandMachaelaCruzLabel.Visible = 'off'; % Hide the label
            % app.GamePagePanel.Visible = 'on'; % Show the game page panel
            
             app.Image8.Visible="on";
             app.Image8.Enable = "on";
            image1 = 'resized_red_image.jpg';
            image2 = 'resized_yellow.jpg';
            image3 = 'resized_green.jpg';
            image4 = 'resized_blue.jpg';
            image5 = 'resized_purple.png';
            image6 = 'resized_pink.jpg';
          
            % Display each image for 2 seconds
                images = {image1, image2, image3, image4, image5, image6};
                for i = 1:numel(images)
                    app.Image8.ImageSource = images{i};
                    %subplot(1, 1, 1);
                    %imshow(images{i});
                    %title(sprintf('Image %d', i));
                    pause(2); % Pause for 2 seconds
                end
            app.Image8.Visible="off";
            app.Image8.Enable = "off";
                
        end

        % Button pushed function: TakethequizButton
        function TakeQuizButton(app, event)
            app.LetsPlayButton.Visible = 'off'; % Hide the button
            app.LetsPlayButton.Enable = 'off';
            app.TakethequizButton.Visible = 'off'; % Hide the button
            app.TakethequizButton.Enable = 'off';

            image1 = 'resized_red_image.jpg';
            image2 = 'resized_yellow.jpg';
            image3 = 'resized_green.jpg';
            image4 = 'resized_blue.jpg';
            image5 = 'resized_purple.jpg';
            image6 = 'resized_pink.jpg';
            edited_image1 = 'resized_light_red.jpg';
            edited_image2 = 'resized_dark_yellow.jpg';
            edited_image3 = 'resized_light_green.jpg';
            edited_image4 = 'resized_dark_blue.jpg';
            edited_image5 = 'resized_light_purple.jpg';
            edited_image6 = 'resized_darkened_pink.jpg';

            % 
            images = {image1, image2, image3, image4, image5, image6};
            edited_images = {edited_image1, edited_image2, edited_image3, edited_image4, edited_image5, edited_image6};
       
           randomImages = images(randperm(numel(images)));
    
    for i = 2:7
        randomIndex = randi(numel(randomImages));
        
        % Set the image source to the corresponding edited image
        app.(sprintf('Image%d', i)).ImageSource = randomImages{randomIndex};
        app.(sprintf('Image%d', i)).Visible = 'on';
        app.(sprintf('Image%d', i)).Enable = 'on';
        
        randomImages(randomIndex) = [];

         % Get a random index for edited images
        % randomeditedIndex = randi(numel(edited_images));
        % 
        % % Remove the selected edited image to avoid repetition
        % edited_images(randomeditedIndex) = [];
    end

    for j = randperm(numel(2:7))
        %  % Get a random index for edited images
        % randomeditedIndex = randi(numel(edited_images));

         % Set the image source to the corresponding edited image
        app.(sprintf('Image%d', j)).ImageSource = edited_images{j};
        app.(sprintf('Image%d', j)).Visible = 'on';
        app.(sprintf('Image%d', j)).Enable = 'on';

        % Remove the selected edited image to avoid repetition
        % edited_images(j) = [];
    end


            % app.Image2.ImageSource = images{randomIndex2};
            % app.Image2.Visible = 'on';
            % app.Image3.ImageSource = images{randomIndex3};
            % app.Image3.Visible = 'on';
            % app.Image4.ImageSource = images{randomIndex4};
            % app.Image4.Visible = 'on';
            % app.Image5.ImageSource = images{randomIndex5};
            % app.Image5.Visible = 'on';
            % app.Image6.ImageSource = images{randomIndex6};
            % app.Image6.Visible = 'on';
            % app.Image7.ImageSource = images{randomIndex7};
            % app.Image7.Visible = 'on';

            % app.Image7.Visible = 'on';
            % app.Image6.Visible = 'on';
            % app.Image5.Visible = 'on';
            % app.Image4.Visible = 'on';
            % app.Image3.Visible = 'on';
            % app.BlueButton.Visible = 'on';
            % app.GreenButton.Visible = 'on';
            % app.PinkButton.Visible = 'on';
            % app.RedButton.Visible = 'on';
            % app.YellowButton.Visible = 'on';
            % app.PurpleButton.Visible = 'on';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BackgroundHome.png');
            app.BrainGameLabel = uilabel(app.UIFigure);
            app.BrainGameLabel.Text = 'Brain Game ';
            app.CreatedbyKareenaSandhuSalinaMariMentorandMachaelaCruzLabel = uilabel(app.UIFigure);
            app.CreatedbyKareenaSandhuSalinaMariMentorandMachaelaCruzLabel.Text = 'Created by Kareena Sandhu, Salina-Mari Mentor, and Machaela Cruz';
            app.LetsPlayButton = uibutton(app.UIFigure, 'push');
            app.LetsPlayButton.ButtonPushedFcn = createCallbackFcn(app, @LetsPlayButtonPushed, true);
            app.LetsPlayButton.Text = 'Let''s Play!';
            app.ClickLetsPlaytobeginLabel = uilabel(app.UIFigure);
            app.ClickLetsPlaytobeginLabel.Text = 'Click "Let''s Play!" to begin';
            app.TakethequizButton = uibutton(app.UIFigure, 'push');
            app.TakethequizButton.ButtonPushedFcn = createCallbackFcn(app, @TakeQuizButton, true);
            app.TakethequizButton.Text = 'Take the quiz!';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'purple_image.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'lighter_red_image.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.Visible = 'off';
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'yellow_image.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.Visible = 'off';
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'blue_image.jpg');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'green_image.png');
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.Visible = 'off';
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'pink_image.jpg');
            app.BlueButton = uibutton(app.UIFigure, 'push');
            app.BlueButton.Visible = 'off';
            app.BlueButton.Text = 'Blue';
            app.GreenButton = uibutton(app.UIFigure, 'push');
            app.GreenButton.Visible = 'off';
            app.GreenButton.Text = 'Green';
            app.PinkButton = uibutton(app.UIFigure, 'push');
            app.PinkButton.Visible = 'off';
            app.PinkButton.Text = 'Pink';
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.Visible = 'off';
            app.RedButton.Text = 'Red';
            app.PurpleButton = uibutton(app.UIFigure, 'push');
            app.PurpleButton.Visible = 'off';
            app.PurpleButton.Text = 'Purple';
            app.YellowButton = uibutton(app.UIFigure, 'push');
            app.YellowButton.Visible = 'off';
            app.YellowButton.Text = 'Yellow';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.Enable = 'off';
            app.Image8.Visible = 'off';
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'BackgroundHome.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HomePage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end